classdef SimoneVoto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SimoneDeLuca2022Label      matlab.ui.control.Label
        VotoEditField_16           matlab.ui.control.NumericEditField
        VotoEditField_16Label      matlab.ui.control.Label
        CalcoloButton              matlab.ui.control.Button
        MediaPesataEditField       matlab.ui.control.NumericEditField
        MediaPesataEditFieldLabel  matlab.ui.control.Label
        CFUEditField_1             matlab.ui.control.NumericEditField
        CFUEditFieldLabel          matlab.ui.control.Label
        VotoEditField_1            matlab.ui.control.NumericEditField
        VotoEditField_15Label      matlab.ui.control.Label
        CFUEditField_12            matlab.ui.control.NumericEditField
        CFUEditField_12Label       matlab.ui.control.Label
        CFUEditField_11            matlab.ui.control.NumericEditField
        CFUEditField_11Label       matlab.ui.control.Label
        CFUEditField_10            matlab.ui.control.NumericEditField
        CFUEditField_10Label       matlab.ui.control.Label
        CFUEditField_9             matlab.ui.control.NumericEditField
        CFUEditField_9Label        matlab.ui.control.Label
        CFUEditField_8             matlab.ui.control.NumericEditField
        CFUEditField_8Label        matlab.ui.control.Label
        CFUEditField_7             matlab.ui.control.NumericEditField
        CFUEditField_7Label        matlab.ui.control.Label
        CFUEditField_6             matlab.ui.control.NumericEditField
        CFUEditField_6Label        matlab.ui.control.Label
        CFUEditField_5             matlab.ui.control.NumericEditField
        CFUEditField_5Label        matlab.ui.control.Label
        CFUEditField_4             matlab.ui.control.NumericEditField
        CFUEditField_4Label        matlab.ui.control.Label
        CFUEditField_3             matlab.ui.control.NumericEditField
        CFUEditField_3Label        matlab.ui.control.Label
        CFUEditField_2             matlab.ui.control.NumericEditField
        CFUEditField_2Label        matlab.ui.control.Label
        VotoEditField_12           matlab.ui.control.NumericEditField
        VotoEditField_12Label      matlab.ui.control.Label
        VotoEditField_11           matlab.ui.control.NumericEditField
        VotoEditField_11Label      matlab.ui.control.Label
        VotoEditField_10           matlab.ui.control.NumericEditField
        VotoEditField_10Label      matlab.ui.control.Label
        VotoEditField_9            matlab.ui.control.NumericEditField
        VotoEditField_9Label       matlab.ui.control.Label
        VotoEditField_8            matlab.ui.control.NumericEditField
        VotoEditField_8Label       matlab.ui.control.Label
        VotoEditField_7            matlab.ui.control.NumericEditField
        VotoEditField_7Label       matlab.ui.control.Label
        VotoEditField_6            matlab.ui.control.NumericEditField
        VotoEditField_6Label       matlab.ui.control.Label
        VotoEditField_5            matlab.ui.control.NumericEditField
        VotoEditField_5Label       matlab.ui.control.Label
        VotoEditField_4            matlab.ui.control.NumericEditField
        VotoEditField_4Label       matlab.ui.control.Label
        VotoEditField_3            matlab.ui.control.NumericEditField
        VotoEditField_3Label       matlab.ui.control.Label
        VotoEditField_2            matlab.ui.control.NumericEditField
        VotoEditField_2Label       matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MediaPesataEditField
        function MediaPesataEditFieldValueChanged(app, event)
    
        end

        % Button pushed function: CalcoloButton
        function CalcoloButtonPushed(app, event)
		    voto1= app.VotoEditField_1.Value;
            voto2= app.VotoEditField_2.Value;
            voto3= app.VotoEditField_3.Value;
            voto4= app.VotoEditField_4.Value;
            voto5= app.VotoEditField_5.Value;
            voto6= app.VotoEditField_6.Value;
            voto7= app.VotoEditField_7.Value;
            voto8= app.VotoEditField_8.Value;
            voto9= app.VotoEditField_9.Value;
            voto10= app.VotoEditField_10.Value;
            voto11= app.VotoEditField_11.Value;
            voto12= app.VotoEditField_12.Value;

		cfu1   = app.CFUEditField_1.Value;
		cfu2   = app.CFUEditField_2.Value;
		cfu3   = app.CFUEditField_3.Value;
		cfu4   = app.CFUEditField_4.Value;
		cfu5   = app.CFUEditField_5.Value;
		cfu6   = app.CFUEditField_6.Value;
		cfu7   = app.CFUEditField_7.Value;
		cfu8   = app.CFUEditField_8.Value;
		cfu9   = app.CFUEditField_9.Value;
		cfu10  = app.CFUEditField_10.Value;
		cfu11  = app.CFUEditField_11.Value;
		cfu12  = app.CFUEditField_12.Value;
        
        %media pesata
        media = (voto1*cfu1+voto2*cfu2+voto3*cfu3+voto4*cfu4+voto5*cfu5+voto6*cfu6+voto7*cfu7+voto8*cfu8+voto9*cfu9+voto10*cfu10+voto11*cfu11+voto12*cfu12)/(cfu1+cfu2+cfu3+cfu4+cfu5+cfu6+cfu7+cfu8+cfu9+cfu10+cfu11+cfu12);
        votopresentazione = media *110/30; 
        
        app.MediaPesataEditField.Value=media;
        app.VotoEditField_16.Value=votopresentazione;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VotoEditField_2Label = uilabel(app.UIFigure);
            app.VotoEditField_2Label.Text = 'Voto';
            app.VotoEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VotoEditField_3Label = uilabel(app.UIFigure);
            app.VotoEditField_3Label.Text = 'Voto';
            app.VotoEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.VotoEditField_4Label = uilabel(app.UIFigure);
            app.VotoEditField_4Label.Text = 'Voto';
            app.VotoEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.VotoEditField_5Label = uilabel(app.UIFigure);
            app.VotoEditField_5Label.Text = 'Voto';
            app.VotoEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.VotoEditField_6Label = uilabel(app.UIFigure);
            app.VotoEditField_6Label.Text = 'Voto';
            app.VotoEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.VotoEditField_7Label = uilabel(app.UIFigure);
            app.VotoEditField_7Label.Text = 'Voto';
            app.VotoEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.VotoEditField_8Label = uilabel(app.UIFigure);
            app.VotoEditField_8Label.Text = 'Voto';
            app.VotoEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.VotoEditField_9Label = uilabel(app.UIFigure);
            app.VotoEditField_9Label.Text = 'Voto';
            app.VotoEditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.VotoEditField_10Label = uilabel(app.UIFigure);
            app.VotoEditField_10Label.Text = 'Voto';
            app.VotoEditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.VotoEditField_11Label = uilabel(app.UIFigure);
            app.VotoEditField_11Label.Text = 'Voto';
            app.VotoEditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.VotoEditField_12Label = uilabel(app.UIFigure);
            app.VotoEditField_12Label.Text = 'Voto';
            app.VotoEditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditField_2Label = uilabel(app.UIFigure);
            app.CFUEditField_2Label.Text = 'CFU';
            app.CFUEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditField_3Label = uilabel(app.UIFigure);
            app.CFUEditField_3Label.Text = 'CFU';
            app.CFUEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditField_4Label = uilabel(app.UIFigure);
            app.CFUEditField_4Label.Text = 'CFU';
            app.CFUEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditField_5Label = uilabel(app.UIFigure);
            app.CFUEditField_5Label.Text = 'CFU';
            app.CFUEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditField_6Label = uilabel(app.UIFigure);
            app.CFUEditField_6Label.Text = 'CFU';
            app.CFUEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditField_7Label = uilabel(app.UIFigure);
            app.CFUEditField_7Label.Text = 'CFU';
            app.CFUEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditField_8Label = uilabel(app.UIFigure);
            app.CFUEditField_8Label.Text = 'CFU';
            app.CFUEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditField_9Label = uilabel(app.UIFigure);
            app.CFUEditField_9Label.Text = 'CFU';
            app.CFUEditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditField_10Label = uilabel(app.UIFigure);
            app.CFUEditField_10Label.Text = 'CFU';
            app.CFUEditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditField_11Label = uilabel(app.UIFigure);
            app.CFUEditField_11Label.Text = 'CFU';
            app.CFUEditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditField_12Label = uilabel(app.UIFigure);
            app.CFUEditField_12Label.Text = 'CFU';
            app.CFUEditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.VotoEditField_15Label = uilabel(app.UIFigure);
            app.VotoEditField_15Label.Text = 'Voto';
            app.VotoEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.CFUEditFieldLabel = uilabel(app.UIFigure);
            app.CFUEditFieldLabel.Text = 'CFU';
            app.CFUEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.MediaPesataEditFieldLabel = uilabel(app.UIFigure);
            app.MediaPesataEditFieldLabel.Text = 'Media Pesata';
            app.MediaPesataEditField = uieditfield(app.UIFigure, 'numeric');
            app.MediaPesataEditField.ValueChangedFcn = createCallbackFcn(app, @MediaPesataEditFieldValueChanged, true);
            app.CalcoloButton = uibutton(app.UIFigure, 'push');
            app.CalcoloButton.ButtonPushedFcn = createCallbackFcn(app, @CalcoloButtonPushed, true);
            app.CalcoloButton.Text = 'Calcolo';
            app.VotoEditField_16Label = uilabel(app.UIFigure);
            app.VotoEditField_16Label.Text = 'Voto ';
            app.VotoEditField_16 = uieditfield(app.UIFigure, 'numeric');
            app.SimoneDeLuca2022Label = uilabel(app.UIFigure);
            app.SimoneDeLuca2022Label.Text = 'Simone De Luca 2022';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simonevoto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end